classdef cuedConditioningGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        qCondUIFigure             matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        HardwarecommandsPanel     matlab.ui.container.Panel
        TrialButton               matlab.ui.control.Button
        reverse                   matlab.ui.control.Button
        forward                   matlab.ui.control.Button
        RewardButton              matlab.ui.control.Button
        CueButton                 matlab.ui.control.Button
        FakeCueButton             matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        USBPortDropDownLabel      matlab.ui.control.Label
        USBPortDropDown           matlab.ui.control.DropDown
        ConnectButton             matlab.ui.control.StateButton
        Panel_3                   matlab.ui.container.Panel
        CurrentExperimentLabel    matlab.ui.control.Label
        curFile                   matlab.ui.control.Label
        browseButton              matlab.ui.control.Button
        NewExpButton              matlab.ui.control.Button
        DeletefileButton          matlab.ui.control.Button
        CurrentEventLabel         matlab.ui.control.Label
        currEventTxt              matlab.ui.control.Label
        RecButton                 matlab.ui.control.StateButton
        TotTrialsEditFieldLabel   matlab.ui.control.Label
        TotTrialsEditField        matlab.ui.control.NumericEditField
        StartButton               matlab.ui.control.StateButton
        InterstimsEditFieldLabel  matlab.ui.control.Label
        interstim                 matlab.ui.control.NumericEditField
        RandTimesEditFieldLabel   matlab.ui.control.Label
        randTime                  matlab.ui.control.NumericEditField
        ProbrewardLabel           matlab.ui.control.Label
        probReward                matlab.ui.control.NumericEditField
        CameraPanel               matlab.ui.container.Panel
        connCamera                matlab.ui.control.StateButton
        live                      matlab.ui.control.Button
        ProbfakeCueLabel          matlab.ui.control.Label
        probFakeCue               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        serialObj            % Object storing the serial communication with arduino
        defPath = [];
        currFile
    end
    
    properties (Access = public)
        cmdDone = false
        currEvent = '';
        fileID = [];
        expRunning = false;
        
        src = [];
        vid = [];
        settings = [];
        
    end
    
    methods (Access = private)
        function enableAll(app,state)
            app.USBPortDropDown.Enable = state;
            app.ConnectButton.Enable = state;
            
            app.TrialButton.Enable = state;
            app.RewardButton.Enable = state;
            app.reverse.Enable = state;
            app.CueButton.Enable = state;
            app.FakeCueButton.Enable = state;
            app.forward.Enable = state;
            
            %app.browseButton.Enable = state;
            app.NewExpButton.Enable = state;
            %app.DeletefileButton.Enable = state;
            
        end
        
        function serialReceiver(app,~,~)
            msg = readline(app.serialObj);
            app.currEvent = msg;
            
            if isempty(msg)
                message = 'Command execution not confirmed by Arduino.';
                uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                return
            elseif strcmpi(msg, '1')
                app.cmdDone = true;
            else
                if app.RecButton.Value
                    splitMsg = strsplit(msg,'_');
                    app.UITable.Data = cat(1, splitMsg, app.UITable.Data);
                    fprintf(app.fileID, [datestr(now,'YYYYmmDD_hhMMss') ',' splitMsg{1} ',' splitMsg{2} '\n']);
                end
                app.currEventTxt.Text = msg;
            end

        end
        
        function enableRec(app,state)
            app.browseButton.Enable = state;
            app.NewExpButton.Enable = state;
            app.DeletefileButton.Enable = state;
            
            app.RecButton.Enable = state;
            
            app.TrialButton.Enable = state;
            app.RewardButton.Enable = state;
            app.CueButton.Enable = state;
            app.FakeCueButton.Enable = state;
            app.forward.Enable = state;
            app.reverse.Enable = state;
        end
        
        function onlyCue(app)
            app.cmdDone = false;
            writeline(app.serialObj,'onlyCue')
            
        end
        
        function bool = randomReward(~,probability)
            randomDice = rand(1);
            if randomDice <= probability
                bool = true;
            else
                bool = false;
            end
        end
        
        function doTrial(app)
            app.currEventTxt.Text = 'GUI trial';
            app.cmdDone = false;
            writeline(app.serialObj,'trial')
        end
        
        function doReward(app)
            app.currEventTxt.Text = 'GUI reward';
            app.cmdDone = false;
            writeline(app.serialObj,'reward')
        end
        
        function doCue(app)
            app.currEventTxt.Text = 'GUI cue';
            app.cmdDone = false;
            writeline(app.serialObj,'cue')
        end
        
        function doFakeCue(app)
            app.currEventTxt.Text = 'GUI wrong cue';
            app.cmdDone = false;
            writeline(app.serialObj,'wrongCue')
        end
        
        function setCameraPrefs(app)
            % Brightness
            if isnan(app.settings.Brightness)
                tmp = propinfo(app.src,'Brightness');
                app.src.Brightness = tmp.ConstraintValue(1);    % Set minimum brightness level
            else
                app.src.Brightness = app.settings.Brightness;
            end
            % Exposure
            app.src.ExposureMode = app.settings.ExposureMode;
            % Gamma
            app.src.GammaMode = app.settings.GammaMode;
            % Sharpness
            app.src.SharpnessMode = app.settings.SharpnessMode;
            % Framerate
            app.src.FrameRateMode = app.settings.FrameRateMode;
            if isnan(app.settings.FrameRate)
                tmp = propinfo(app.src,'FrameRate');
                app.src.FrameRate = tmp.DefaultValue;       % Set default Framerate
                app.settings.FrameRate = tmp.DefaultValue;
            else
                app.src.FrameRate = app.settings.FrameRate;
            end
            % Gain
            app.src.GainMode = app.settings.gainMode;
            if isnan(app.settings.Gain)
                tmp = propinfo(app.src,'Gain');
                app.src.Gain = tmp.DefaultValue;            % Set default Gain
                app.settings.Gain = tmp.DefaultValue; 
            else
                app.src.Gain = app.settings.Gain;
            end
            % ShutterSpeed
            app.src.ShutterMode = app.settings.ShutterMode;
            if isnan(app.settings.Shutter)
                tmp = propinfo(app.src,'Shutter');
                app.src.Shutter = tmp.DefaultValue;
                app.settings.Shutter = tmp.DefaultValue;
            else
                app.src.Shutter = app.settings.Shutter;
            end
            
            % Trigger
            if strcmpi(app.settings.TriggerMode,'hardware')
                triggerconfig(app.vid, app.settings.TriggerMode,...
                    app.settings.TriggerCondition,...
                    app.settings.TriggerSource);
            else
                triggerconfig(app.vid, app.settings.TriggerMode);
            end
            
        end
        
        function bool = randomFakeCue(app, probability)
            randomDice = rand(1);
            if randomDice <= probability
                bool = true;
            else
                bool = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Detect COM ports and fills the drop down menu
            ports = serialportlist("available");
            if isempty(ports)
                app.USBPortDropDown.Items = "None";
                app.USBPortDropDown.Value = "None";
            else
                app.USBPortDropDown.Items = ports;
                app.USBPortDropDown.Value = ports(end);
            end
        
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                selectedPort = app.USBPortDropDown.Value;
                app.serialObj = serialport(selectedPort, 250000, 'Timeout',5);
                configureCallback(app.serialObj,"terminator",@app.serialReceiver)
                
                % Update GUI
                app.ConnectButton.Text = 'Connecting...';
                app.ConnectButton.BackgroundColor = [.93 .69 .13];
                warning('off','serialport:serialport:ReadlineWarning');
                
                pause(2)                % Required for the Serial Obj to fully create
                flush(app.serialObj);   % Clear serial port device buffers
                
                % Handshake to verify the connection
                writeline(app.serialObj,'handshakeFromMATLAB');
                msg = readline(app.serialObj);
                if strcmpi(msg, 'handshakeFromArduino')
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.BackgroundColor = [.39 .83 .07];
                else
                    message = sprintf('Selected COM port was not responsive.\nNot an arduino or wrong firmware on the arduino');
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    delete(app.serialObj);
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = 'Connect';
                    app.ConnectButton.BackgroundColor = [.96 .96 .96];
                end
                
            else
                delete(app.serialObj);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [.96 .96 .96];
                
            end
            warning('on','serialport:serialport:ReadlineWarning')
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            tit = 'Select a file to append data';
            if isempty(app.defPath)
                filter = '*.txt';
            else
                filter = [app.defPath '*.txt'];
            end
            [f, p] = uigetfile(filter,tit);
            figure(app.qCondUIFigure)
            
            if ~isequal(f,0)
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = f;
            end
        end

        % Button pushed function: NewExpButton
        function NewExpButtonPushed(app, event)
            tit = 'Where to save the new exp?';
            defName = ['qCond_' datestr(now,'YYYYmmDD-hhMMss')];
            
            p = uigetdir(app.defPath,tit);                 
            figure(app.qCondUIFigure)
            
            if ~isequal(p,0)
                foldName = datestr(now,'YYYYmmDD-hhMMss');
                mkdir(p,foldName)
                f = [defName '.txt'];
                
                p = [p filesep foldName filesep];
                fID = fopen([p f], 'wt+', 'n', 'UTF-8');
                fclose(fID);
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = p;
            end
        end

        % Button pushed function: DeletefileButton
        function DeletefileButtonPushed(app, event)
            fN = [app.defPath app.currFile];
            if exist(fN, 'file')
                delete(fN)
                app.currFile = [];
                app.curFile.Text = 'none';
            end
        end

        % Button pushed function: forward
        function forwardPushed(app, event)
            app.currEventTxt.Text = 'GUI forward';
            app.cmdDone = false;
            writeline(app.serialObj,'forward')
            enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
                enableAll(app,'on');
                app.currEvent = '';
            end
            enableAll(app,'on');
            app.currEvent = '';
        end

        % Button pushed function: reverse
        function reverseButtonPushed(app, event)
            app.currEventTxt.Text = 'GUI reverse';
            app.cmdDone = false;
            writeline(app.serialObj,'reverse')
            enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
                enableAll(app,'on');
                app.currEvent = '';
            end
            enableAll(app,'on');
            app.currEvent = '';
        end

        % Button pushed function: RewardButton
        function RewardButtonPushed(app, event)
            doReward(app)
        end

        % Button pushed function: TrialButton
        function TrialButtonPushed(app, event)
            doTrial(app)
        end

        % Callback function
        function StopExpButtonPushed(app, event)
            writeline(app.serialObj,'stopExp')
            waitfor(app,'cmdDone',true)
            app.currEvent = '';
        end

        % Value changed function: RecButton
        function RecButtonValueChanged(app, event)
            value = app.RecButton.Value;
            if value
                flush(app.serialObj);
                app.browseButton.Enable = false;
                app.NewExpButton.Enable = false;
                app.DeletefileButton.Enable = false;
                try
                    app.fileID = fopen([app.defPath app.currFile],'wt+', 'n', 'UTF-8');
                catch ME
                    message = 'Current file Invalid.';
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    app.RecButton.Value = false;
                    
                    app.browseButton.Enable = true;
                    app.NewExpButton.Enable = true;
                    app.DeletefileButton.Enable = true;
                end
            else
                try
                    fclose(app.fileID);
                catch ME
                end
                
                app.browseButton.Enable = true;
                app.NewExpButton.Enable = true;
                app.DeletefileButton.Enable = true;
                
                app.UITable.Data = [];
                app.fileID = [];
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
            if value
                app.expRunning = true;
                enableRec(app,'off')
                
                interStimTime = app.interstim.Value;
                randMaxTime = app.randTime.Value;
                
                app.vid.FramesPerTrigger = inf;
                if isvalid(app.vid) && app.RecButton.Value
                    app.vid.FramesPerTrigger = inf;
                    start(app.vid)
                    
                    cueTime = zeros(app.TotTrialsEditField.Value,1);
                    writeline(app.serialObj,'trigger')
                    trigger(app.vid)
                    cueTic = tic;
                end
                

                % Cycle through all the trials
                for i=1:app.TotTrialsEditField.Value
                    if app.StartButton.Value == false
                        break
                    end
                    
                    % Decide whether or not to give the reward
                    willGiveReward = randomReward(app, app.probReward.Value/100);
                    fakeCue = randomFakeCue(app, app.probFakeCue.Value/100);
                    % Determine a random amount of time to be added to the intertrial
                    randTime = randi(randMaxTime);
                    
                    % INTERTRIAL
                    %-------------------------------------------------
                    tic
                    while toc < (interStimTime+randTime)
                        if app.StartButton.Value == false
                            break
                        end
                    end
                    
                    % TRIAL
                    % ------------------------------------------------
                    if fakeCue
                        doFakeCue(app)
                    else
                        if willGiveReward
                            doTrial(app)
                        else
                            onlyCue(app)
                        end
                    end
                    cueTime(i) = toc(cueTic);
  
                end
                
                % Wait for another intertrial at the end of the recording
                tic
                while toc < (interStimTime)
                    if app.StartButton.Value == false
                        break
                    end
                end
                
                if isvalid(app.vid) && app.RecButton.Value
                    stop(app.vid)
                    [frames, ts] = getdata(app.vid,app.vid.FramesAvailable);
                    
                    [~, f, ~] = fileparts(app.currFile);
                    mFilePath = [app.defPath filesep f];
                    v = VideoWriter([app.defPath filesep f '.avi'], 'Motion JPEG AVI');
                    v.FrameRate = app.src.FrameRate;
                    open(v);
                    enableAll(app,'off')
                    
                    app.curFile.BackgroundColor = [.93 .69 .13];
                    app.curFile.Text = 'WRITING VIDEO FILE...';
                    
                    writeVideo(v,frames);
                    close(v);
                    
                    app.curFile.BackgroundColor = [.96 .96 .96];
                    app.curFile.Text = app.defPath;
                    
                    enableAll(app,'on')
                    m = matfile(mFilePath,'Writable',true);
                    m.ts = ts; 
                    m.cueTime = cueTime;
                end
                enableRec(app,'on')
                app.StartButton.Value = false;
                app.expRunning = false;
                
                % Uncheck rec button
                try
                    fclose(app.fileID);
                catch ME
                end
                app.browseButton.Enable = true;
                app.NewExpButton.Enable = true;
                app.DeletefileButton.Enable = true;
                app.UITable.Data = [];
                app.fileID = [];
                app.RecButton.Value = false;
                
            else
                app.expRunning = false;
            end
        end

        % Button pushed function: CueButton
        function CueButtonPushed(app, event)
            doCue(app)
        end

        % Value changed function: connCamera
        function connCameraValueChanged(app, event)
            value = app.connCamera.Value;
            if value
                app.settings = getDefaultCameraSettings();
                try
                    app.connCamera.Text = 'Connecting...';
                    app.connCamera.BackgroundColor = [.93 .69 .13];
                    
                    app.vid = videoinput('pointgrey', app.settings.deviceID , app.settings.format);
                    app.src = getselectedsource(app.vid);
                    setCameraPrefs(app)
                    app.live.Enable = 'on';
                    
                    app.connCamera.Text = 'Connected';
                    app.connCamera.BackgroundColor = [.39 .83 .07];
                catch me
                    message = 'Unable to load camera.';
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    if ~ isempty(app.vid) && isvalid(app.vid)
                        delete(app.vid)
                    end
                    app.connCamera.Value = false;
                    app.live.Enable = 'off';
                    app.connCamera.Text = 'Connect';
                    app.connCamera.BackgroundColor = [.96 .96 .96];
                end
                
            else
                delete(app.vid)
                app.live.Enable = 'off';
                
                app.connCamera.Text = 'Connect';
                app.connCamera.BackgroundColor = [.96 .96 .96];
            end
        end

        % Button pushed function: live
        function liveButtonPushed(app, event)
            cameraPreview(app.vid, app.src, app.settings)
        end

        % Close request function: qCondUIFigure
        function qCondUIFigureCloseRequest(app, event)
            if ~isempty(app.vid) && isvalid(app.vid)
                delete(app.vid)
            end
            delete(app)
            
        end

        % Button pushed function: FakeCueButton
        function FakeCueButtonPushed(app, event)
            doFakeCue(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.qCondUIFigure = uifigure('Visible', 'off');
            app.qCondUIFigure.Name = 'qCond';
            app.qCondUIFigure.CloseRequestFcn = createCallbackFcn(app, @qCondUIFigureCloseRequest, true);
            app.UITable = uitable(app.qCondUIFigure);
            app.UITable.ColumnName = {'Timestamp'; 'Event'};
            app.UITable.RowName = {};
            app.HardwarecommandsPanel = uipanel(app.qCondUIFigure);
            app.HardwarecommandsPanel.Title = 'Hardware commands';
            app.TrialButton = uibutton(app.HardwarecommandsPanel, 'push');
            app.TrialButton.ButtonPushedFcn = createCallbackFcn(app, @TrialButtonPushed, true);
            app.TrialButton.Text = 'Trial';
            app.reverse = uibutton(app.HardwarecommandsPanel, 'push');
            app.reverse.ButtonPushedFcn = createCallbackFcn(app, @reverseButtonPushed, true);
            app.reverse.Text = '<<';
            app.forward = uibutton(app.HardwarecommandsPanel, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardPushed, true);
            app.forward.Text = '>>';
            app.RewardButton = uibutton(app.HardwarecommandsPanel, 'push');
            app.RewardButton.ButtonPushedFcn = createCallbackFcn(app, @RewardButtonPushed, true);
            app.RewardButton.Text = 'Reward';
            app.CueButton = uibutton(app.HardwarecommandsPanel, 'push');
            app.CueButton.ButtonPushedFcn = createCallbackFcn(app, @CueButtonPushed, true);
            app.CueButton.Text = 'Cue';
            app.FakeCueButton = uibutton(app.HardwarecommandsPanel, 'push');
            app.FakeCueButton.ButtonPushedFcn = createCallbackFcn(app, @FakeCueButtonPushed, true);
            app.FakeCueButton.Text = 'Fake Cue';
            app.Panel_2 = uipanel(app.qCondUIFigure);
            app.USBPortDropDownLabel = uilabel(app.Panel_2);
            app.USBPortDropDownLabel.Text = 'USB Port';
            app.USBPortDropDown = uidropdown(app.Panel_2);
            app.ConnectButton = uibutton(app.Panel_2, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Panel_3 = uipanel(app.qCondUIFigure);
            app.CurrentExperimentLabel = uilabel(app.Panel_3);
            app.CurrentExperimentLabel.Text = 'Current Experiment:';
            app.curFile = uilabel(app.Panel_3);
            app.curFile.Text = 'none';
            app.browseButton = uibutton(app.Panel_3, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Enable = 'off';
            app.browseButton.Text = 'browse';
            app.NewExpButton = uibutton(app.Panel_3, 'push');
            app.NewExpButton.ButtonPushedFcn = createCallbackFcn(app, @NewExpButtonPushed, true);
            app.NewExpButton.Text = 'New Exp.';
            app.DeletefileButton = uibutton(app.Panel_3, 'push');
            app.DeletefileButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefileButtonPushed, true);
            app.DeletefileButton.Enable = 'off';
            app.DeletefileButton.Text = 'Delete file';
            app.CurrentEventLabel = uilabel(app.qCondUIFigure);
            app.CurrentEventLabel.Text = 'Current Event:';
            app.currEventTxt = uilabel(app.qCondUIFigure);
            app.currEventTxt.Text = 'none';
            app.RecButton = uibutton(app.qCondUIFigure, 'state');
            app.RecButton.ValueChangedFcn = createCallbackFcn(app, @RecButtonValueChanged, true);
            app.RecButton.Text = 'Rec';
            app.TotTrialsEditFieldLabel = uilabel(app.qCondUIFigure);
            app.TotTrialsEditFieldLabel.Text = 'Tot Trials';
            app.TotTrialsEditField = uieditfield(app.qCondUIFigure, 'numeric');
            app.TotTrialsEditField.Limits = [1 100];
            app.TotTrialsEditField.Value = 50;
            app.StartButton = uibutton(app.qCondUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.InterstimsEditFieldLabel = uilabel(app.qCondUIFigure);
            app.InterstimsEditFieldLabel.Text = 'Interstim (s)';
            app.interstim = uieditfield(app.qCondUIFigure, 'numeric');
            app.interstim.Limits = [0 50];
            app.interstim.Value = 15;
            app.RandTimesEditFieldLabel = uilabel(app.qCondUIFigure);
            app.RandTimesEditFieldLabel.Text = 'Rand.Time (s)';
            app.randTime = uieditfield(app.qCondUIFigure, 'numeric');
            app.randTime.Limits = [0 50];
            app.randTime.Value = 3;
            app.ProbrewardLabel = uilabel(app.qCondUIFigure);
            app.ProbrewardLabel.Text = 'Prob. reward (%)';
            app.probReward = uieditfield(app.qCondUIFigure, 'numeric');
            app.probReward.Limits = [0 100];
            app.probReward.Value = 100;
            app.CameraPanel = uipanel(app.qCondUIFigure);
            app.CameraPanel.Title = 'Camera';
            app.connCamera = uibutton(app.CameraPanel, 'state');
            app.connCamera.ValueChangedFcn = createCallbackFcn(app, @connCameraValueChanged, true);
            app.connCamera.Text = 'Connect';
            app.live = uibutton(app.CameraPanel, 'push');
            app.live.ButtonPushedFcn = createCallbackFcn(app, @liveButtonPushed, true);
            app.live.Enable = 'off';
            app.live.Text = 'Live';
            app.ProbfakeCueLabel = uilabel(app.qCondUIFigure);
            app.ProbfakeCueLabel.Text = 'Prob. fakeCue (%)';
            app.probFakeCue = uieditfield(app.qCondUIFigure, 'numeric');
            app.probFakeCue.Limits = [0 100];
            app.probFakeCue.Value = 30;
            % Show the figure after all components are created
            app.qCondUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cuedConditioningGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.qCondUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.qCondUIFigure)
        end
    end
end